classdef batchProcessing_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        checkbox_lempelZiv           matlab.ui.control.CheckBox
        checkbox_hurstExp            matlab.ui.control.CheckBox
        checkbox_LLExp               matlab.ui.control.CheckBox
        checkbox_fracDim             matlab.ui.control.CheckBox
        checkbox_apEn                matlab.ui.control.CheckBox
        checkbox_sampEn              matlab.ui.control.CheckBox
        checkbox_waveletMSE          matlab.ui.control.CheckBox
        checkbox_fuzzyEn             matlab.ui.control.CheckBox
        checkbox_permEn              matlab.ui.control.CheckBox
        edit_lempelZiv_m_start       matlab.ui.control.EditField
        edit_hurstExp_m_start        matlab.ui.control.EditField
        edit_LLExp_m_start           matlab.ui.control.EditField
        edit4                        matlab.ui.control.EditField
        edit_apEn_m_start            matlab.ui.control.EditField
        edit_sampEn_m_start          matlab.ui.control.EditField
        edit_waveletMSE_m_start      matlab.ui.control.EditField
        edit_fuzzyEn_m_start         matlab.ui.control.EditField
        edit_permEn_m_start          matlab.ui.control.EditField
        edit_lempelZiv_m_end         matlab.ui.control.EditField
        edit_hurstExp_m_end          matlab.ui.control.EditField
        edit_LLExp_m_end             matlab.ui.control.EditField
        edit_fracDim_m_end           matlab.ui.control.EditField
        edit_apEn_m_end              matlab.ui.control.EditField
        edit_sampEn_m_end            matlab.ui.control.EditField
        edit_waveletMSE_m_end        matlab.ui.control.EditField
        edit_fuzzyEn_m_end           matlab.ui.control.EditField
        edit_permEn_m_end            matlab.ui.control.EditField
        text11                       matlab.ui.control.Label
        edit_lempelZiv_r_start       matlab.ui.control.EditField
        edit_hurstExp_r_start        matlab.ui.control.EditField
        edit_LLExp_r_start           matlab.ui.control.EditField
        edit_fracDim_r_start         matlab.ui.control.EditField
        edit_apEn_r_start            matlab.ui.control.EditField
        edit_sampEn_r_start          matlab.ui.control.EditField
        edit_waveletMSE_r_start      matlab.ui.control.EditField
        edit_fuzzyEn_r_start         matlab.ui.control.EditField
        edit_permEn_r_start          matlab.ui.control.EditField
        edit_lempelZiv_r_end         matlab.ui.control.EditField
        edit_hurstExp_r_end          matlab.ui.control.EditField
        edit_LLExp_r_end             matlab.ui.control.EditField
        edit_fracDim_r_end           matlab.ui.control.EditField
        edit_apEn_r_end              matlab.ui.control.EditField
        edit_waveletMSE_r_end        matlab.ui.control.EditField
        edit_fuzzyEn_r_end           matlab.ui.control.EditField
        edit_permEn_r_end            matlab.ui.control.EditField
        edit_lempelZiv_scale_start   matlab.ui.control.EditField
        edit_hurstExp_scale_start    matlab.ui.control.EditField
        edit_LLExp_scale_start       matlab.ui.control.EditField
        edit_fracDim_scale_start     matlab.ui.control.EditField
        edit_apEn_scale_start        matlab.ui.control.EditField
        edit_waveletMSE_scale_start  matlab.ui.control.EditField
        edit_fuzzyEn_scale_start     matlab.ui.control.EditField
        edit_permEn_scale_start      matlab.ui.control.EditField
        edit_lempelZiv_scale_end     matlab.ui.control.EditField
        edit_hurstExp_scale_end      matlab.ui.control.EditField
        edit_LLExp_scale_end         matlab.ui.control.EditField
        edit_fracDim_scale_end       matlab.ui.control.EditField
        edit_apEn_scale_end          matlab.ui.control.EditField
        edit_sampEn_scale_end        matlab.ui.control.EditField
        edit_waveletMSE_scale_end    matlab.ui.control.EditField
        edit_fuzzyEn_scale_end       matlab.ui.control.EditField
        edit_permEn_scale_end        matlab.ui.control.EditField
        text29                       matlab.ui.control.Label
        edit_fracDim_k_start         matlab.ui.control.EditField
        text30                       matlab.ui.control.Label
        edit_fuzzyEn_n_start         matlab.ui.control.EditField
        text31                       matlab.ui.control.Label
        edit_fuzzyEn_tau_start       matlab.ui.control.EditField
        text32                       matlab.ui.control.Label
        edit_permEn_delay_start      matlab.ui.control.EditField
        text33                       matlab.ui.control.Label
        edit_permEn_order_start      matlab.ui.control.EditField
        checkbox_normalize           matlab.ui.control.CheckBox
        edit_sampEn_r_end            matlab.ui.control.EditField
        edit_sampEn_scale_start      matlab.ui.control.EditField
        edit_inputDir                matlab.ui.control.EditField
        text34                       matlab.ui.control.Label
        btn_inputDir                 matlab.ui.control.Button
        edit_subjectPattern          matlab.ui.control.EditField
        text35                       matlab.ui.control.Label
        edit_fmriPattern             matlab.ui.control.EditField
        text36                       matlab.ui.control.Label
        edit_filePattern             matlab.ui.control.EditField
        text37                       matlab.ui.control.Label
        edit_outputDir               matlab.ui.control.EditField
        text38                       matlab.ui.control.Label
        btn_load                     matlab.ui.control.Button
        text_dataInfo                matlab.ui.control.Label
        btn_run                      matlab.ui.control.Button
        btn_outputDir                matlab.ui.control.Button
        pb_brainMask                 matlab.ui.control.Button
        edit_fuzzyEn_tau_end         matlab.ui.control.EditField
        edit_permEn_delay_end        matlab.ui.control.EditField
        edit_permEn_order_end        matlab.ui.control.EditField
        edit_fuzzyEn_n_end           matlab.ui.control.EditField
        edit_fracDim_k_end           matlab.ui.control.EditField
        btn_viewOutput               matlab.ui.control.Button
        text40                       matlab.ui.control.Label
        text41                       matlab.ui.control.Label
        text2                        matlab.ui.control.Label
        text46                       matlab.ui.control.Label
        text47                       matlab.ui.control.Label
        text48                       matlab.ui.control.Label
        text49                       matlab.ui.control.Label
        text50                       matlab.ui.control.Label
        text51                       matlab.ui.control.Label
        text52                       matlab.ui.control.Label
        text53                       matlab.ui.control.Label
        text54                       matlab.ui.control.Label
        text55                       matlab.ui.control.Label
        text56                       matlab.ui.control.Label
        text57                       matlab.ui.control.Label
        text58                       matlab.ui.control.Label
        text59                       matlab.ui.control.Label
        text60                       matlab.ui.control.Label
        text61                       matlab.ui.control.Label
        text62                       matlab.ui.control.Label
        text63                       matlab.ui.control.Label
        text64                       matlab.ui.control.Label
        text65                       matlab.ui.control.Label
        text66                       matlab.ui.control.Label
        text67                       matlab.ui.control.Label
        text68                       matlab.ui.control.Label
        text69                       matlab.ui.control.Label
        SaveLoadProjectPanel         matlab.ui.container.Panel
        pushbutton11                 matlab.ui.control.Button
        pushbutton12                 matlab.ui.control.Button
    end

    
    methods (Access = private)
        function ap_en_call(app, handles, mask, m_start, m_end, r_start, r_end, m_scale, r_scale)
            imgSize = size(mask);
            brainVox = find(mask == max(mask(:)));
            for m = m_start:m_scale:m_end
                for r = r_start:r_scale:r_end
                    ApEn = zeros(imgSize);
                    msg = ['calculating self ApEn: m=',num2str(m),',r=',num2str(r)];
                    h = waitbar(0,msg);
                    nFail = 0;
                    for vox = 1:length(brainVox)
                        [row, col, sl] = ind2sub(imgSize, brainVox(vox));
                        TS1 = squeeze(handles.img_4D(row, col, sl, :));
                        TS2 = TS1;
                        r_val = r * std(double(TS1));
                        tmp = cross_approx_entropy(m, r_val, TS1, TS2);
                        ApEn(row, col, sl) = tmp(1);
                        nFail = nFail + tmp(2);
                        waitbar(vox/length(brainVox));
                    end
                    close(h);
                    opFname = [handles.outputDir, filesep, handles.baseName, 'ApEn_m', ...
                        num2str(m), '_r', num2str(r*100), 'per','.nii'];
                    niiStruct = make_nii(ApEn, handles.imgVoxDim, [], 64, []);
                    niiStruct.hdr.hk.data_type = 'float64';
                    save_nii(niiStruct, opFname, []);
                end
            end
        end
        
        function ans = select_brain_mask(app, hObject, handles, imgStruct)
             [fname, pname] = uigetfile('*.*','Select the brain mask');
            
            if (fname==0 & pname==0)
                disp('Brain mask not selected');
            else
                mask_file = [pname, fname];
                mask = load_nii(mask_file);
                if (size(mask.img) ~= size(imgStruct.img_4D(:,:,:,1)))
                    msgbox('Mask and Input image dimensions do not match');
                else
                    handles.brainMask = mask.img;
                    guidata(hObject, handles);
                end
            end
            ans=mask.img;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function batchProcessing_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to batchProcessing (see VARARGIN)
            
            % Choose default command line output for batchProcessing
            handles.output = hObject;
            handles.lempelZiv = 0;
            handles.hurstExp = 0;
            handles.LLExp = 0;
            handles.fracDim = 0;
            handles.apEn = 0;
            handles.sampEn = 0;
            handles.waveletMSE = 0;
            handles.fuzzyEn = 0;
            handles.permEn = 0;
            
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: btn_inputDir
        function btn_inputDir_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_inputDir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dirName = uigetdir;
            handles.inputDir = dirName;
            set(handles.edit_inputDir, 'string', handles.inputDir);
            guidata(hObject, handles);
        end

        % Button pushed function: btn_load
        function btn_load_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_load (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            A = sum(strcmp(fieldnames(handles),'inputDir'));
            B = sum(strcmp(fieldnames(handles),'outputDir'));
            ipChk = [A B];
            clear A B;
            subjectPattern = get(handles.edit_subjectPattern, 'String');
            fmriPattern = get(handles.edit_fmriPattern, 'String');
            filePattern = get(handles.edit_filePattern, 'String');
            subject_search = strcat(handles.inputDir,filesep,subjectPattern);
            file_search = strcat(handles.inputDir,filesep,subjectPattern,filesep,fmriPattern,filesep,filePattern);
            subjects = dir(subject_search);
            files = dir(file_search);
            set(handles.text_dataInfo,'string',([num2str(length(subjects)), ' subjects, ', num2str(length(files)), ' scans loaded']));
            disp(filePattern)
            switch lower(filePattern)
                case '*.nii'
                    disp('inside 3d case');
                    for i=1:length(files)
                         folders{i}=[files(i).folder];
                    end
            
                    temp = unique(folders,'stable');
                    subFolders=temp;
                    disp(subFolders);
                    imgStruct = readImages4D(subFolders{1});
                    %handles.brainMask=select_brain_mask(hObject,handles,imgStruct);
                    for k = 1 :  length(subFolders)
                        thisSubFolder = subFolders{k};
                        fprintf('Found sub: %s.\n', thisSubFolder);
                        imgStruct = readImages4D(thisSubFolder);
                        handle(k).img_4D = imgStruct.img_4D;
                        handle(k).baseName = imgStruct.bName;
                        handle(k).imgVoxDim = imgStruct.voxDim;
                        handle(k).size=length(subFolders);
                        if(handles.batchmask_flag==0)
                            handle(k).brainMask=handles.mask;
                        end
                    end
                    handles.scans=handle;
                    guidata(hObject,handles);
            
                case '*.nii.gz'
                    disp('inside 4d case');
                    opStruct = struct([]);
                    for i=1:length(files)
                        fullpath = [files(i).folder,filesep,files(i).name];
                        disp(fullpath);
                        imgStruct = niftiread(fullpath);
                        opStruct(i).img_4D = imgStruct;
                        [p,f,e] = fileparts(files(i).name);
                        [p,f,e] = fileparts(f);
                        opStruct(i).bName = f;
                        %if (size(handles.mask) ~= size(opStruct(i).img_4D(:,:,:,1)))
                        %    msgbox('Mask and Input image dimensions do not match');
                        %end
                        folder = [handles.outputDir,filesep,'tmp'];
                        gunzip(fullpath,folder);
                        tmp_files = dir(folder);
                        for j=1:length(tmp_files)
                            if strcmp(tmp_files(j).name,'.')
                                continue
                            elseif strcmp(tmp_files(j).name,'..')
                                continue
                            end
                            disp('Loading nii');
                            imgStruct = load_nii_hdr([folder, filesep, tmp_files(j).name]);
                            delete([folder, filesep, tmp_files(j).name]);
                            opStruct(i).originator = imgStruct.hist.originator(1:3);
                            opStruct(i).voxDim = imgStruct.dime.pixdim(2:4);
                        end
                    end
                    handles.scans = opStruct;
                    %disp(handles.scans(1));
                    %disp(handles.scans(2));
                    %disp(handles.scans(3));
                    guidata(hObject, handles);
            
            end
            disp('Done reading input images');
        end

        % Button pushed function: btn_outputDir
        function btn_outputDir_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_outputDir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % opFolder = uigetdir;
            % handles.opFolder = opFolder;
            % guidata(hObject, handles);
            dirName = uigetdir;
            handles.outputDir = dirName;
            set(handles.edit_outputDir, 'string', handles.outputDir);
            mkdir([handles.outputDir,filesep,'tmp']);
            guidata(hObject, handles);
        end

        % Button pushed function: btn_run
        function btn_run_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_run (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if handles.lempelZiv
                C = sum(strcmp(fieldnames(handles),'lempelZiv_m_start'));
                D = sum(strcmp(fieldnames(handles),'lempelZiv_m_end'));
                E = sum(strcmp(fieldnames(handles),'lempelZiv_r_start'));
                F = sum(strcmp(fieldnames(handles),'lempelZiv_r_end'));
                G = sum(strcmp(fieldnames(handles),'lempelZiv_scale_start'));
                H = sum(strcmp(fieldnames(handles),'lempelZiv_scale_end'));
                ipChk = [C D E F G H];
                clear C D E F G H
                set(handles.edit_lempelZiv_m_start, 'Enable', 'off');
                set(handles.edit_lempelZiv_m_end, 'Enable', 'off');
                set(handles.edit_lempelZiv_r_start, 'Enable', 'off');
                set(handles.edit_lempelZiv_r_end, 'Enable', 'off');
                set(handles.edit_lempelZiv_scale_start, 'Enable', 'off');
                set(handles.edit_lempelZiv_scale_end, 'Enable', 'off');
                for k=1:length(handles.arr)
                    handle=handles.arr(k);
                    disp(handle.baseName)
                    lempel_ziv_call(handles, handles.brainMask, handles.lempelZiv_m_start, handles.lempelZiv_m_end, handles.lempelZiv_r_start, handles.lempelZiv_r_end, handles.lempelZiv_scale_start, handles.lempelZiv_scale_end);
                end
            
            end
            
            if handles.hurstExp
                C = sum(strcmp(fieldnames(handles),'hurstExp_m_start'));
                D = sum(strcmp(fieldnames(handles),'hurstExp_m_end'));
                E = sum(strcmp(fieldnames(handles),'hurstExp_r_start'));
                F = sum(strcmp(fieldnames(handles),'hurstExp_r_end'));
                G = sum(strcmp(fieldnames(handles),'hurstExp_scale_start'));
                H = sum(strcmp(fieldnames(handles),'hurstExp_scale_end'));
                ipChk = [C D E F G H];
                clear C D E F G H
                set(handles.edit_hurstExp_m_start, 'Enable', 'off');
                set(handles.edit_hurstExp_m_end, 'Enable', 'off');
                set(handles.edit_hurstExp_r_start, 'Enable', 'off');
                set(handles.edit_hurstExp_r_end, 'Enable', 'off');
                set(handles.edit_hurstExp_scale_start, 'Enable', 'off');
                set(handles.edit_hurstExp_scale_end, 'Enable', 'off');
                for k=1:length(handles.arr)
                    handle=handles.arr(k);
                    disp(handle.baseName)
                    %call or hurst
                end
            end
            if handles.LLExp
                C = sum(strcmp(fieldnames(handles),'LLExp_m_start'));
                D = sum(strcmp(fieldnames(handles),'LLExp_m_end'));
                E = sum(strcmp(fieldnames(handles),'LLExp_r_start'));
                F = sum(strcmp(fieldnames(handles),'LLExp_r_end'));
                G = sum(strcmp(fieldnames(handles),'LLExp_scale_start'));
                H = sum(strcmp(fieldnames(handles),'LLExp_scale_end'));
                ipChk = [C D E F G H];
                clear C D E F G H
                set(handles.edit_LLExp_m_start, 'Enable', 'off');
                set(handles.edit_LLExp_m_end, 'Enable', 'off');
                set(handles.edit_LLExp_r_start, 'Enable', 'off');
                set(handles.edit_LLExp_r_end, 'Enable', 'off');
                set(handles.edit_LLExp_scale_start, 'Enable', 'off');
                set(handles.edit_LLExp_scale_end, 'Enable', 'off');
                for k=1:length(handles.arr)
                    handle=handles.arr(k);
                    disp(handle.baseName)
                    %ap_en_call(handle, handle.brainMask, 2, 3, 0.3, 0.4, 1,0.1);
                    %call for llexp
                end
            end
            if handles.fracDim
                C = sum(strcmp(fieldnames(handles),'fracDim_m_start'));
                D = sum(strcmp(fieldnames(handles),'fracDim_m_end'));
                E = sum(strcmp(fieldnames(handles),'fracDim_r_start'));
                F = sum(strcmp(fieldnames(handles),'fracDim_r_end'));
                G = sum(strcmp(fieldnames(handles),'fracDim_scale_start'));
                H = sum(strcmp(fieldnames(handles),'fracDim_scale_end'));
                I = sum(strcmp(fieldnames(handles),'fracDim_k_start'));
                J = sum(strcmp(fieldnames(handles),'fracDim_k_end'));
                ipChk = [C D E F G H I J];
                clear C D E F G H I J
                set(handles.edit_fracDim_m_start, 'Enable', 'off');
                set(handles.edit_fracDim_m_end, 'Enable', 'off');
                set(handles.edit_fracDim_r_start, 'Enable', 'off');
                set(handles.edit_fracDim_r_end, 'Enable', 'off');
                set(handles.edit_fracDim_scale_start, 'Enable', 'off');
                set(handles.edit_fracDim_scale_end, 'Enable', 'off');
                set(handles.edit_fracDim_k_start, 'Enable', 'off');
                set(handles.edit_fracDim_k_end, 'Enable', 'off');
                for k=1:length(handles.arr)
                handle=handles.arr(k);
                disp(handle.baseName)
                frac_dim_call(handles, handles.brainMask, handles.fracDim_k_start, handles.fracDim_k_end, handles.fracDim_scale_start);
                end
            
            end
            
            if handles.apEn
                C = sum(strcmp(fieldnames(handles),'apEn_m_start'));
                D = sum(strcmp(fieldnames(handles),'apEn_m_end'));
                E = sum(strcmp(fieldnames(handles),'apEn_r_start'));
                F = sum(strcmp(fieldnames(handles),'apEn_r_end'));
                G = sum(strcmp(fieldnames(handles),'apEn_scale_start'));
                H = sum(strcmp(fieldnames(handles),'apEn_scale_end'));
                ipChk = [C D E F G H];
                clear C D E F G H
                set(handles.edit_apEn_m_start, 'Enable', 'off');
                set(handles.edit_apEn_m_end, 'Enable', 'off');
                set(handles.edit_apEn_r_start, 'Enable', 'off');
                set(handles.edit_apEn_r_end, 'Enable', 'off');
                set(handles.edit_apEn_scale_start, 'Enable', 'off');
                set(handles.edit_apEn_scale_end, 'Enable', 'off');
                disp(length(handles.scans));
            
                for i = 1:length(handles.scans)
                    %<----for 3d testing--->
                       handle=handles.scans(i);
                       handle.outputDir=handles.outputDir;
                       disp(handles.outputDir);
                        if(handles.batchmask_flag==1)
                            handles.mask=load_untouch_nii(handles.mask_arr(i)).img;
                        end
                       ap_en_call(app, handle, handles.mask, handles.apEn_m_start, handles.apEn_m_end, handles.apEn_r_start, handles.apEn_r_end, handles.apEn_scale_start, handles.apEn_scale_end);
                    %<--------------------->
            %         scan_data = struct();
            %         scan_data.img_4D = handles.scans(i).img_4D;
            %         disp(size(handles.scans(i).img_4D));
            %         scan_data.outputDir = handles.outputDir;
            %         scan_data.baseName = handles.scans(i).bName;
            %         scan_data.imgVoxDim = handles.scans(i).voxDim;
            %         scan_data.originator = handles.scans(i).originator;
            %         disp(['Computing ApEn for ',scan_data.baseName]);
            %         if(handles.batchmask_flag==1)
            %             handles.mask=handles.mask_arr(i);
            %         end
            %         ap_en_call(scan_data, handles.mask, handles.apEn_m_start, handles.apEn_m_end, handles.apEn_r_start, handles.apEn_r_end, handles.apEn_scale_start, handles.apEn_scale_end);
            
                end
            end
            if handles.sampEn
                C = sum(strcmp(fieldnames(handles),'sampEn_m_start'));
                D = sum(strcmp(fieldnames(handles),'sampEn_m_end'));
                E = sum(strcmp(fieldnames(handles),'sampEn_r_start'));
                F = sum(strcmp(fieldnames(handles),'sampEn_r_end'));
                G = sum(strcmp(fieldnames(handles),'sampEn_scale_start'));
                H = sum(strcmp(fieldnames(handles),'sampEn_scale_end'));
                ipChk = [C D E F G H];
                clear C D E F G H
                set(handles.edit_sampEn_m_start, 'Enable', 'off');
                set(handles.edit_sampEn_m_end, 'Enable', 'off');
                set(handles.edit_sampEn_r_start, 'Enable', 'off');
                set(handles.edit_sampEn_r_end, 'Enable', 'off');
                set(handles.edit_sampEn_scale_start, 'Enable', 'off');
                set(handles.edit_sampEn_scale_end, 'Enable', 'off');
                for k=1:length(handles.arr)
                    handle=handles.arr(k);
                    disp(handle.baseName)
                    samp_en_call(handles, handles.brainMask, handles.sampEn_m_start, handles.sampEn_m_end, handles.sampEn_r_start, handles.sampEn_r_end, handles.sampEn_scale_start, handles.sampEn_scale_end);
                end
            
            end
            if handles.waveletMSE
                C = sum(strcmp(fieldnames(handles),'waveletMSE_m_start'));
                D = sum(strcmp(fieldnames(handles),'waveletMSE_m_end'));
                E = sum(strcmp(fieldnames(handles),'waveletMSE_r_start'));
                F = sum(strcmp(fieldnames(handles),'waveletMSE_r_end'));
                G = sum(strcmp(fieldnames(handles),'waveletMSE_scale_start'));
                H = sum(strcmp(fieldnames(handles),'waveletMSE_scale_end'));
                ipChk = [C D E F G H];
                clear C D E F G H
                set(handles.edit_waveletMSE_m_start, 'Enable', 'off');
                set(handles.edit_waveletMSE_m_end, 'Enable', 'off');
                set(handles.edit_waveletMSE_r_start, 'Enable', 'off');
                set(handles.edit_waveletMSE_r_end, 'Enable', 'off');
                set(handles.edit_waveletMSE_scale_start, 'Enable', 'off');
                set(handles.edit_waveletMSE_scale_end, 'Enable', 'off');
                for k=1:length(handles.arr)
                    handle=handles.arr(k);
                    disp(handle.baseName)
                    %call for wavelength mse
                end
            
            end
            if handles.fuzzyEn
                C = sum(strcmp(fieldnames(handles),'fuzzyEn_m_start'));
                D = sum(strcmp(fieldnames(handles),'fuzzyEn_m_end'));
                E = sum(strcmp(fieldnames(handles),'fuzzyEn_r_start'));
                F = sum(strcmp(fieldnames(handles),'fuzzyEn_r_end'));
                G = sum(strcmp(fieldnames(handles),'fuzzyEn_scale_start'));
                H = sum(strcmp(fieldnames(handles),'fuzzyEn_scale_end'));
                I = sum(strcmp(fieldnames(handles),'fuzzyEn_n_start'));
                J = sum(strcmp(fieldnames(handles),'fuzzyEn_n_end'));
                K = sum(strcmp(fieldnames(handles),'fuzzyEn_tau_start'));
                L = sum(strcmp(fieldnames(handles),'fuzzyEn_tau_end'));
                ipChk = [C D E F G H I J K L];
                clear C D E F G H I J K L
                set(handles.edit_fuzzyEn_m_start, 'Enable', 'off');
                set(handles.edit_fuzzyEn_m_end, 'Enable', 'off');
                set(handles.edit_fuzzyEn_r_start, 'Enable', 'off');
                set(handles.edit_fuzzyEn_r_end, 'Enable', 'off');
                set(handles.edit_fuzzyEn_scale_start, 'Enable', 'off');
                set(handles.edit_fuzzyEn_scale_end, 'Enable', 'off');
                set(handles.edit_fuzzyEn_n_start, 'Enable', 'off');
                set(handles.edit_fuzzyEn_n_end, 'Enable', 'off');
                set(handles.edit_fuzzyEn_tau_start, 'Enable', 'off');
                set(handles.edit_fuzzyEn_tau_end, 'Enable', 'off');
            end
            if handles.permEn
                C = sum(strcmp(fieldnames(handles),'permEn_m_start'));
                D = sum(strcmp(fieldnames(handles),'permEn_m_end'));
                E = sum(strcmp(fieldnames(handles),'permEn_r_start'));
                F = sum(strcmp(fieldnames(handles),'permEn_r_end'));
                G = sum(strcmp(fieldnames(handles),'permEn_scale_start'));
                H = sum(strcmp(fieldnames(handles),'permEn_scale_end'));
                I = sum(strcmp(fieldnames(handles),'permEn_ord_start'));
                J = sum(strcmp(fieldnames(handles),'permEn_ord_end'));
                K = sum(strcmp(fieldnames(handles),'permEn_delay_start'));
                L = sum(strcmp(fieldnames(handles),'permEn_delay_end'));
                ipChk = [C D E F G H I J K L];
                clear C D E F G H I J K L
                set(handles.edit_permEn_m_start, 'Enable', 'off');
                set(handles.edit_permEn_m_end, 'Enable', 'off');
                set(handles.edit_permEn_r_start, 'Enable', 'off');
                set(handles.edit_permEn_r_end, 'Enable', 'off');
                set(handles.edit_permEn_scale_start, 'Enable', 'off');
                set(handles.edit_permEn_scale_end, 'Enable', 'off');
                set(handles.edit_permEn_n_start, 'Enable', 'off');
                set(handles.edit_permEn_n_end, 'Enable', 'off');
                set(handles.edit_permEn_tau_start, 'Enable', 'off');
                set(handles.edit_permEn_tau_end, 'Enable', 'off');
                for k=1:length(handles.arr)
                    handle=handles.arr(k);
                    disp(handle.baseName)
                    %call for perm en
                end
            end
        end

        % Button pushed function: btn_viewOutput
        function btn_viewOutput_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_viewOutput (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            files = dir([handles.outputDir, filesep, '*.nii']);
            for i=1:length(files)
                final_files{i} = [files(i).folder, filesep, files(i).name];
            end
            displayImage(final_files);
        end

        % Value changed function: checkbox_LLExp
        function checkbox_LLExp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_LLExp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_LLExp
            value = get(hObject,'Value');
            handles.LLExp = value;
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_apEn
        function checkbox_apEn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_apEn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_apEn
            value = get(hObject,'Value');
            handles.apEn = value;
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_fracDim
        function checkbox_fracDim_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_fracDim (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_fracDim
            value = get(hObject,'Value');
            handles.fracDim = value;
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_fuzzyEn
        function checkbox_fuzzyEn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_fuzzyEn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_fuzzyEn
            value = get(hObject,'Value');
            handles.fuzzyEn = value;
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_hurstExp
        function checkbox_hurstExp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_hurstExp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_hurstExp
            value = get(hObject,'Value');
            handles.hurstExp = value;
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_lempelZiv
        function checkbox_lempelZiv_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_lempelZiv (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_lempelZiv
            value = get(hObject,'Value');
            handles.lempelZiv = value;
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_permEn
        function checkbox_permEn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_permEn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_permEn
            value = get(hObject,'Value');
            handles.permEn = value;
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_sampEn
        function checkbox_sampEn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_sampEn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_sampEn
            value = get(hObject,'Value');
            handles.sampEn = value;
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_waveletMSE
        function checkbox_waveletMSE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_waveletMSE (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_waveletMSE
            value = get(hObject,'Value');
            handles.waveletMSE = value;
            guidata(hObject, handles);
        end

        % Value changed function: edit_LLExp_m_end
        function edit_LLExp_m_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_LLExp_m_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_LLExp_m_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_LLExp_m_end as a double
            m_end = get(hObject,'String');
            m_end = str2num(m_end);
            handles.LLExp_m_end = m_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_LLExp_m_start
        function edit_LLExp_m_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_LLExp_m_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_LLExp_m_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_LLExp_m_start as a double
            m_start = get(hObject,'String');
            m_start = str2num(m_start);
            handles.LLExp_m_start = m_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_LLExp_r_end
        function edit_LLExp_r_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_LLExp_r_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_LLExp_r_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_LLExp_r_end as a double
            r_end = get(hObject,'String');
            r_end = str2num(r_end);
            handles.LLExp_r_end = r_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_LLExp_r_start
        function edit_LLExp_r_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_LLExp_r_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_LLExp_r_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_LLExp_r_start as a double
            r_start = get(hObject,'String');
            r_start = str2num(r_start);
            handles.LLExp_r_start = r_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_LLExp_scale_end
        function edit_LLExp_scale_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_LLExp_scale_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_LLExp_scale_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_LLExp_scale_end as a double
            scale_end = get(hObject,'String');
            scale_end = str2num(scale_end);
            handles.LLExp_scale_end = scale_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_LLExp_scale_start
        function edit_LLExp_scale_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_LLExp_scale_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_LLExp_scale_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_LLExp_scale_start as a double
            scale_start = get(hObject,'String');
            scale_start = str2num(scale_start);
            handles.LLExp_scale_start = scale_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_apEn_m_end
        function edit_apEn_m_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_apEn_m_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_apEn_m_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_apEn_m_end as a double
            m_end = get(hObject,'String');
            m_end = str2num(m_end);
            handles.apEn_m_end = m_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_apEn_m_start
        function edit_apEn_m_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_apEn_m_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_apEn_m_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_apEn_m_start as a double
            m_start = get(hObject,'String');
            m_start = str2num(m_start);
            handles.apEn_m_start = m_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_apEn_r_end
        function edit_apEn_r_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_apEn_r_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_apEn_r_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_apEn_r_end as a double
            r_end = get(hObject,'String');
            r_end = str2num(r_end);
            handles.apEn_r_end = r_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_apEn_r_start
        function edit_apEn_r_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_apEn_r_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_apEn_r_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_apEn_r_start as a double
            r_start = get(hObject,'String');
            r_start = str2num(r_start);
            handles.apEn_r_start = r_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_apEn_scale_end
        function edit_apEn_scale_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_apEn_scale_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_apEn_scale_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_apEn_scale_end as a double
            scale_end = get(hObject,'String');
            scale_end = str2num(scale_end);
            handles.apEn_scale_end = scale_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_apEn_scale_start
        function edit_apEn_scale_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_apEn_scale_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_apEn_scale_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_apEn_scale_start as a double
            scale_start = get(hObject,'String');
            scale_start = str2num(scale_start);
            handles.apEn_scale_start = scale_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fracDim_k_end
        function edit_fracDim_k_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fracDim_k_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fracDim_k_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_fracDim_k_end as a double
            k_end = get(hObject,'String');
            k_end = str2num(k_end);
            handles.fracDim_k_end = k_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fracDim_k_start
        function edit_fracDim_k_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fracDim_k_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fracDim_k_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_fracDim_k_start as a double
            k_start = get(hObject,'String');
            k_start = str2num(k_start);
            handles.fracDim_k_start = k_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fracDim_m_end
        function edit_fracDim_m_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fracDim_m_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fracDim_m_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_fracDim_m_end as a double
            m_end = get(hObject,'String');
            m_end = str2num(m_end);
            handles.fracDim_m_end = m_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fracDim_r_end
        function edit_fracDim_r_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fracDim_r_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fracDim_r_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_fracDim_r_end as a double
            r_end = get(hObject,'String');
            r_end = str2num(r_end);
            handles.fracDim_r_end = r_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fracDim_r_start
        function edit_fracDim_r_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fracDim_r_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fracDim_r_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_fracDim_r_start as a double
            r_start = get(hObject,'String');
            r_start = str2num(r_start);
            handles.fracDim_r_start = r_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fracDim_scale_end
        function edit_fracDim_scale_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fracDim_scale_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fracDim_scale_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_fracDim_scale_end as a double
            scale_end = get(hObject,'String');
            scale_end = str2num(scale_end);
            handles.fracDim_scale_end = scale_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fracDim_scale_start
        function edit_fracDim_scale_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fracDim_scale_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fracDim_scale_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_fracDim_scale_start as a double
            scale_start = get(hObject,'String');
            scale_start = str2num(scale_start);
            handles.fracDim_scale_start = scale_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fuzzyEn_m_end
        function edit_fuzzyEn_m_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fuzzyEn_m_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fuzzyEn_m_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_fuzzyEn_m_end as a double
            m_end = get(hObject,'String');
            m_end = str2num(m_end);
            handles.fuzzyEn_m_end = m_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fuzzyEn_m_start
        function edit_fuzzyEn_m_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fuzzyEn_m_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fuzzyEn_m_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_fuzzyEn_m_start as a double
            m_start = get(hObject,'String');
            m_start = str2num(m_start);
            handles.fuzzyEn_m_start = m_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fuzzyEn_n_end
        function edit_fuzzyEn_n_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fuzzyEn_n_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fuzzyEn_n_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_fuzzyEn_n_end as a double
            n_end = get(hObject,'String');
            n_end = str2num(n_end);
            handles.fuzzyEn_n_end = n_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fuzzyEn_n_start
        function edit_fuzzyEn_n_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fuzzyEn_n_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fuzzyEn_n_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_fuzzyEn_n_start as a double
            n_start = get(hObject,'String');
            n_start = str2num(n_start);
            handles.fuzzyEn_n_start = n_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fuzzyEn_r_end
        function edit_fuzzyEn_r_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fuzzyEn_r_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fuzzyEn_r_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_fuzzyEn_r_end as a double
            r_end = get(hObject,'String');
            r_end = str2num(r_end);
            handles.fuzzyEn_r_end = r_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fuzzyEn_r_start
        function edit_fuzzyEn_r_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fuzzyEn_r_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fuzzyEn_r_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_fuzzyEn_r_start as a double
            r_start = get(hObject,'String');
            r_start = str2num(r_start);
            handles.fuzzyEn_r_start = r_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fuzzyEn_scale_end
        function edit_fuzzyEn_scale_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fuzzyEn_scale_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fuzzyEn_scale_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_fuzzyEn_scale_end as a double
            scale_end = get(hObject,'String');
            scale_end = str2num(scale_end);
            handles.fuzzyEn_scale_end = scale_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fuzzyEn_scale_start
        function edit_fuzzyEn_scale_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fuzzyEn_scale_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fuzzyEn_scale_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_fuzzyEn_scale_start as a double
            scale_start = get(hObject,'String');
            scale_start = str2num(scale_start);
            handles.fuzzyEn_scale_start = scale_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fuzzyEn_tau_end
        function edit_fuzzyEn_tau_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fuzzyEn_tau_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fuzzyEn_tau_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_fuzzyEn_tau_end as a double
            tau_end = get(hObject,'String');
            tau_end = str2num(tau_end);
            handles.fuzzyEn_tau_end = tau_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_fuzzyEn_tau_start
        function edit_fuzzyEn_tau_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fuzzyEn_tau_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fuzzyEn_tau_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_fuzzyEn_tau_start as a double
            tau_start = get(hObject,'String');
            tau_start = str2num(tau_start);
            handles.fuzzyEn_tau_start = tau_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_hurstExp_m_end
        function edit_hurstExp_m_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_hurstExp_m_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_hurstExp_m_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_hurstExp_m_end as a double
            m_end = get(hObject,'String');
            m_end = str2num(m_end);
            handles.hurstExp_m_end = m_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_hurstExp_m_start
        function edit_hurstExp_m_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_hurstExp_m_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_hurstExp_m_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_hurstExp_m_start as a double
            m_start = get(hObject,'String');
            m_start = str2num(m_start);
            handles.hurstExp_m_start = m_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_hurstExp_r_end
        function edit_hurstExp_r_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_hurstExp_r_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_hurstExp_r_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_hurstExp_r_end as a double
            r_end = get(hObject,'String');
            r_end = str2num(r_end);
            handles.hurstExp_r_end = r_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_hurstExp_r_start
        function edit_hurstExp_r_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_hurstExp_r_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_hurstExp_r_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_hurstExp_r_start as a double
            r_start = get(hObject,'String');
            r_start = str2num(r_start);
            handles.hurstExp_r_start = r_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_hurstExp_scale_end
        function edit_hurstExp_scale_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_hurstExp_scale_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_hurstExp_scale_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_hurstExp_scale_end as a double
            scale_end = get(hObject,'String');
            scale_end = str2num(scale_end);
            handles.hurstExp_scale_end = scale_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_hurstExp_scale_start
        function edit_hurstExp_scale_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_hurstExp_scale_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_hurstExp_scale_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_hurstExp_scale_start as a double
            scale_start = get(hObject,'String');
            scale_start = str2num(scale_start);
            handles.hurstExp_scale_start = scale_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_lempelZiv_m_end
        function edit_lempelZiv_m_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_lempelZiv_m_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_lempelZiv_m_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_lempelZiv_m_end as a double
            m_end = get(hObject,'String');
            m_end = str2num(m_end);
            handles.lempelZiv_m_end = m_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_lempelZiv_m_start
        function edit_lempelZiv_m_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_lempelZiv_m_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_lempelZiv_m_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_lempelZiv_m_start as a double
            m_start = get(hObject,'String');
            m_start = str2num(m_start);
            handles.lempelZiv_m_start = m_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_lempelZiv_r_end
        function edit_lempelZiv_r_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_lempelZiv_r_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_lempelZiv_r_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_lempelZiv_r_end as a double
            r_end = get(hObject,'String');
            r_end = str2num(r_end);
            handles.lempelZiv_r_end = r_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_lempelZiv_r_start
        function edit_lempelZiv_r_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_lempelZiv_r_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_lempelZiv_r_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_lempelZiv_r_start as a double
            r_start = get(hObject,'String');
            r_start = str2num(r_start);
            handles.lempelZiv_r_start = r_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_lempelZiv_scale_end
        function edit_lempelZiv_scale_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_lempelZiv_scale_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_lempelZiv_scale_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_lempelZiv_scale_end as a double
            scale_end = get(hObject,'String');
            scale_end = str2num(scale_end);
            handles.lempelZiv_scale_end = scale_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_lempelZiv_scale_start
        function edit_lempelZiv_scale_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_lempelZiv_scale_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_lempelZiv_scale_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_lempelZiv_scale_start as a double
            scale_start = get(hObject,'String');
            scale_start = str2num(scale_start);
            handles.lempelZiv_scale_start = scale_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_permEn_delay_end
        function edit_permEn_delay_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_permEn_delay_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_permEn_delay_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_permEn_delay_end as a double
            delay_end = get(hObject,'String');
            delay_end = str2num(delay_end);
            handles.permEn_delay_end = delay_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_permEn_delay_start
        function edit_permEn_delay_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_permEn_delay_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_permEn_delay_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_permEn_delay_start as a double
            delay_start = get(hObject,'String');
            delay_start = str2num(delay_start);
            handles.permEn_delay_start = delay_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_permEn_m_end
        function edit_permEn_m_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_permEn_m_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_permEn_m_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_permEn_m_end as a double
            m_end = get(hObject,'String');
            m_end = str2num(m_end);
            handles.permEn_m_end = m_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_permEn_m_start
        function edit_permEn_m_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_permEn_m_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_permEn_m_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_permEn_m_start as a double
            m_start = get(hObject,'String');
            m_start = str2num(m_start);
            handles.permEn_m_start = m_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_permEn_order_start
        function edit_permEn_order_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_permEn_order_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_permEn_order_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_permEn_order_start as a double
            ord_start = get(hObject,'String');
            ord_start = str2num(ord_start);
            handles.permEn_ord_start = ord_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_permEn_r_end
        function edit_permEn_r_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_permEn_r_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_permEn_r_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_permEn_r_end as a double
            r_end = get(hObject,'String');
            r_end = str2num(r_end);
            handles.permEn_r_end = r_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_permEn_r_start
        function edit_permEn_r_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_permEn_r_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_permEn_r_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_permEn_r_start as a double
            r_start = get(hObject,'String');
            r_start = str2num(r_start);
            handles.permEn_r_start = r_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_permEn_scale_end
        function edit_permEn_scale_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_permEn_scale_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_permEn_scale_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_permEn_scale_end as a double
            scale_end = get(hObject,'String');
            scale_end = str2num(scale_end);
            handles.permEn_scale_end = scale_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_permEn_scale_start
        function edit_permEn_scale_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_permEn_scale_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_permEn_scale_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_permEn_scale_start as a double
            scale_start = get(hObject,'String');
            scale_start = str2num(scale_start);
            handles.permEn_scale_start = scale_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_sampEn_m_end
        function edit_sampEn_m_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_sampEn_m_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_sampEn_m_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_sampEn_m_end as a double
            m_end = get(hObject,'String');
            m_end = str2num(m_end);
            handles.sampEn_m_end = m_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_sampEn_m_start
        function edit_sampEn_m_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_sampEn_m_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_sampEn_m_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_sampEn_m_start as a double
            m_start = get(hObject,'String');
            m_start = str2num(m_start);
            handles.sampEn_m_start = m_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_sampEn_r_end
        function edit_sampEn_r_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_sampEn_r_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_sampEn_r_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_sampEn_r_end as a double
            r_end = get(hObject,'String');
            r_end = str2num(r_end);
            handles.sampEn_r_end = r_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_sampEn_r_start
        function edit_sampEn_r_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_sampEn_r_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_sampEn_r_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_sampEn_r_start as a double
            r_start = get(hObject,'String');
            r_start = str2num(r_start);
            handles.sampEn_r_start = r_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_sampEn_scale_end
        function edit_sampEn_scale_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_sampEn_scale_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_sampEn_scale_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_sampEn_scale_end as a double
            scale_end = get(hObject,'String');
            scale_end = str2num(scale_end);
            handles.sampEn_scale_end = scale_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_sampEn_scale_start
        function edit_sampEn_scale_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_sampEn_scale_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_sampEn_scale_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_sampEn_scale_start as a double
            scale_start = get(hObject,'String');
            scale_start = str2num(scale_start);
            handles.sampEn_scale_start = scale_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_waveletMSE_m_end
        function edit_waveletMSE_m_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_waveletMSE_m_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_waveletMSE_m_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_waveletMSE_m_end as a double
            m_end = get(hObject,'String');
            m_end = str2num(m_end);
            handles.waveletMSE_m_end = m_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_waveletMSE_m_start
        function edit_waveletMSE_m_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_waveletMSE_m_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_waveletMSE_m_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_waveletMSE_m_start as a double
            m_start = get(hObject,'String');
            m_start = str2num(m_start);
            handles.waveletMSE_m_start = m_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_waveletMSE_r_end
        function edit_waveletMSE_r_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_waveletMSE_r_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_waveletMSE_r_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_waveletMSE_r_end as a double
            r_end = get(hObject,'String');
            r_end = str2num(r_end);
            handles.waveletMSE_r_end = r_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_waveletMSE_r_start
        function edit_waveletMSE_r_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_waveletMSE_r_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_waveletMSE_r_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_waveletMSE_r_start as a double
            r_start = get(hObject,'String');
            r_start = str2num(r_start);
            handles.waveletMSE_r_start = r_start;
            guidata(hObject, handles);
        end

        % Value changed function: edit_waveletMSE_scale_end
        function edit_waveletMSE_scale_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_waveletMSE_scale_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_waveletMSE_scale_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_waveletMSE_scale_end as a double
            scale_end = get(hObject,'String');
            scale_end = str2num(scale_end);
            handles.waveletMSE_scale_end = scale_end;
            guidata(hObject, handles);
        end

        % Value changed function: edit_waveletMSE_scale_start
        function edit_waveletMSE_scale_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_waveletMSE_scale_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_waveletMSE_scale_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_waveletMSE_scale_start as a double
            scale_start = get(hObject,'String');
            scale_start = str2num(scale_start);
            handles.waveletMSE_scale_start = scale_start;
            guidata(hObject, handles);
        end

        % Button pushed function: pb_brainMask
        function pb_brainMask_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_brainMask (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ipFormat = questdlg('How many brain mask would you like to select?', ...
                'Brain Mask Selection','Single','Manual','Batch','Single');
            if isempty(ipFormat)
                disp('Please choose brain mask/s');
            else
                if (strcmp(ipFormat,'Single')==1)
                    [fname, pname] = uigetfile('*.*','Select the brain mask');
                    if (fname==0 & pname==0)
                        disp('Brain mask not selected');
                    else
                        mask_file = [pname, fname];
                        mask = load_untouch_nii(mask_file);
                        handles.mask = mask.img;
                        handles.batchmask_flag=0;
                        guidata(hObject, handles);
                    end
                elseif((strcmp(ipFormat,'Manual')==1))
            
                            [fname, pname]=uigetfile('*','Select the Masks(s)','MultiSelect','on');
                            if (length(fname)==0)
                                disp('No image input selected');
                            else
            
                                for i=1:length(fname)
                                    mask_file=strcat(pname,fname{i});
                                    disp(mask_file)
                                    %mask_arr(i)=load_untouch_nii(mask_file);
                                    mask_arr(i)=mask_file;
                                end
                                handles.mask_arr=mask_arr;
                                handles.batchmask_flag=1;
                                guidata(hObject, handles);
                            end
            
                elseif(strcmp(ipFormat,'Batch')==1)
                       handles.mask_arr=maskbatchProcessing(handles);
                       handles.batchmask_flag=1;
                       guidata(hObject, handles);
                       disp(handles.mask_arr);
                end
            
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'batchProcessing';
            app.figure1.Tag = 'figure1';
            app.checkbox_lempelZiv = uicheckbox(app.figure1);
            app.checkbox_lempelZiv.ValueChangedFcn = createCallbackFcn(app, @checkbox_lempelZiv_Callback, true);
            app.checkbox_lempelZiv.Tag = 'checkbox_lempelZiv';
            app.checkbox_lempelZiv.Text = 'Lempel-Ziv Complexity';
            app.checkbox_hurstExp = uicheckbox(app.figure1);
            app.checkbox_hurstExp.ValueChangedFcn = createCallbackFcn(app, @checkbox_hurstExp_Callback, true);
            app.checkbox_hurstExp.Tag = 'checkbox_hurstExp';
            app.checkbox_hurstExp.Text = 'Hurst Exponent';
            app.checkbox_LLExp = uicheckbox(app.figure1);
            app.checkbox_LLExp.ValueChangedFcn = createCallbackFcn(app, @checkbox_LLExp_Callback, true);
            app.checkbox_LLExp.Tag = 'checkbox_LLExp';
            app.checkbox_LLExp.Text = 'Largest Lyapunov Exponent';
            app.checkbox_fracDim = uicheckbox(app.figure1);
            app.checkbox_fracDim.ValueChangedFcn = createCallbackFcn(app, @checkbox_fracDim_Callback, true);
            app.checkbox_fracDim.Tag = 'checkbox_fracDim';
            app.checkbox_fracDim.Text = 'Higuchi Fractal Dimension';
            app.checkbox_apEn = uicheckbox(app.figure1);
            app.checkbox_apEn.ValueChangedFcn = createCallbackFcn(app, @checkbox_apEn_Callback, true);
            app.checkbox_apEn.Tag = 'checkbox_apEn';
            app.checkbox_apEn.Text = 'ApEn';
            app.checkbox_sampEn = uicheckbox(app.figure1);
            app.checkbox_sampEn.ValueChangedFcn = createCallbackFcn(app, @checkbox_sampEn_Callback, true);
            app.checkbox_sampEn.Tag = 'checkbox_sampEn';
            app.checkbox_sampEn.Text = 'SampEn';
            app.checkbox_waveletMSE = uicheckbox(app.figure1);
            app.checkbox_waveletMSE.ValueChangedFcn = createCallbackFcn(app, @checkbox_waveletMSE_Callback, true);
            app.checkbox_waveletMSE.Tag = 'checkbox_waveletMSE';
            app.checkbox_waveletMSE.Text = 'WaveletMSE';
            app.checkbox_fuzzyEn = uicheckbox(app.figure1);
            app.checkbox_fuzzyEn.ValueChangedFcn = createCallbackFcn(app, @checkbox_fuzzyEn_Callback, true);
            app.checkbox_fuzzyEn.Tag = 'checkbox_fuzzyEn';
            app.checkbox_fuzzyEn.Text = 'Fuzzy Entropy';
            app.checkbox_permEn = uicheckbox(app.figure1);
            app.checkbox_permEn.ValueChangedFcn = createCallbackFcn(app, @checkbox_permEn_Callback, true);
            app.checkbox_permEn.Tag = 'checkbox_permEn';
            app.checkbox_permEn.Text = 'Permutation Entropy';
            app.edit_lempelZiv_m_start = uieditfield(app.figure1, 'text');
            app.edit_lempelZiv_m_start.ValueChangedFcn = createCallbackFcn(app, @edit_lempelZiv_m_start_Callback, true);
            app.edit_lempelZiv_m_start.Tag = 'edit_lempelZiv_m_start';
            app.edit_hurstExp_m_start = uieditfield(app.figure1, 'text');
            app.edit_hurstExp_m_start.ValueChangedFcn = createCallbackFcn(app, @edit_hurstExp_m_start_Callback, true);
            app.edit_hurstExp_m_start.Tag = 'edit_hurstExp_m_start';
            app.edit_LLExp_m_start = uieditfield(app.figure1, 'text');
            app.edit_LLExp_m_start.ValueChangedFcn = createCallbackFcn(app, @edit_LLExp_m_start_Callback, true);
            app.edit_LLExp_m_start.Tag = 'edit_LLExp_m_start';
            app.edit4 = uieditfield(app.figure1, 'text');
            app.edit4.Tag = 'edit4';
            app.edit_apEn_m_start = uieditfield(app.figure1, 'text');
            app.edit_apEn_m_start.ValueChangedFcn = createCallbackFcn(app, @edit_apEn_m_start_Callback, true);
            app.edit_apEn_m_start.Tag = 'edit_apEn_m_start';
            app.edit_sampEn_m_start = uieditfield(app.figure1, 'text');
            app.edit_sampEn_m_start.ValueChangedFcn = createCallbackFcn(app, @edit_sampEn_m_start_Callback, true);
            app.edit_sampEn_m_start.Tag = 'edit_sampEn_m_start';
            app.edit_waveletMSE_m_start = uieditfield(app.figure1, 'text');
            app.edit_waveletMSE_m_start.ValueChangedFcn = createCallbackFcn(app, @edit_waveletMSE_m_start_Callback, true);
            app.edit_waveletMSE_m_start.Tag = 'edit_waveletMSE_m_start';
            app.edit_fuzzyEn_m_start = uieditfield(app.figure1, 'text');
            app.edit_fuzzyEn_m_start.ValueChangedFcn = createCallbackFcn(app, @edit_fuzzyEn_m_start_Callback, true);
            app.edit_fuzzyEn_m_start.Tag = 'edit_fuzzyEn_m_start';
            app.edit_permEn_m_start = uieditfield(app.figure1, 'text');
            app.edit_permEn_m_start.ValueChangedFcn = createCallbackFcn(app, @edit_permEn_m_start_Callback, true);
            app.edit_permEn_m_start.Tag = 'edit_permEn_m_start';
            app.edit_lempelZiv_m_end = uieditfield(app.figure1, 'text');
            app.edit_lempelZiv_m_end.ValueChangedFcn = createCallbackFcn(app, @edit_lempelZiv_m_end_Callback, true);
            app.edit_lempelZiv_m_end.Tag = 'edit_lempelZiv_m_end';
            app.edit_hurstExp_m_end = uieditfield(app.figure1, 'text');
            app.edit_hurstExp_m_end.ValueChangedFcn = createCallbackFcn(app, @edit_hurstExp_m_end_Callback, true);
            app.edit_hurstExp_m_end.Tag = 'edit_hurstExp_m_end';
            app.edit_LLExp_m_end = uieditfield(app.figure1, 'text');
            app.edit_LLExp_m_end.ValueChangedFcn = createCallbackFcn(app, @edit_LLExp_m_end_Callback, true);
            app.edit_LLExp_m_end.Tag = 'edit_LLExp_m_end';
            app.edit_fracDim_m_end = uieditfield(app.figure1, 'text');
            app.edit_fracDim_m_end.ValueChangedFcn = createCallbackFcn(app, @edit_fracDim_m_end_Callback, true);
            app.edit_fracDim_m_end.Tag = 'edit_fracDim_m_end';
            app.edit_apEn_m_end = uieditfield(app.figure1, 'text');
            app.edit_apEn_m_end.ValueChangedFcn = createCallbackFcn(app, @edit_apEn_m_end_Callback, true);
            app.edit_apEn_m_end.Tag = 'edit_apEn_m_end';
            app.edit_sampEn_m_end = uieditfield(app.figure1, 'text');
            app.edit_sampEn_m_end.ValueChangedFcn = createCallbackFcn(app, @edit_sampEn_m_end_Callback, true);
            app.edit_sampEn_m_end.Tag = 'edit_sampEn_m_end';
            app.edit_waveletMSE_m_end = uieditfield(app.figure1, 'text');
            app.edit_waveletMSE_m_end.ValueChangedFcn = createCallbackFcn(app, @edit_waveletMSE_m_end_Callback, true);
            app.edit_waveletMSE_m_end.Tag = 'edit_waveletMSE_m_end';
            app.edit_fuzzyEn_m_end = uieditfield(app.figure1, 'text');
            app.edit_fuzzyEn_m_end.ValueChangedFcn = createCallbackFcn(app, @edit_fuzzyEn_m_end_Callback, true);
            app.edit_fuzzyEn_m_end.Tag = 'edit_fuzzyEn_m_end';
            app.edit_permEn_m_end = uieditfield(app.figure1, 'text');
            app.edit_permEn_m_end.ValueChangedFcn = createCallbackFcn(app, @edit_permEn_m_end_Callback, true);
            app.edit_permEn_m_end.Tag = 'edit_permEn_m_end';
            app.text11 = uilabel(app.figure1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'r';
            app.edit_lempelZiv_r_start = uieditfield(app.figure1, 'text');
            app.edit_lempelZiv_r_start.ValueChangedFcn = createCallbackFcn(app, @edit_lempelZiv_r_start_Callback, true);
            app.edit_lempelZiv_r_start.Tag = 'edit_lempelZiv_r_start';
            app.edit_hurstExp_r_start = uieditfield(app.figure1, 'text');
            app.edit_hurstExp_r_start.ValueChangedFcn = createCallbackFcn(app, @edit_hurstExp_r_start_Callback, true);
            app.edit_hurstExp_r_start.Tag = 'edit_hurstExp_r_start';
            app.edit_LLExp_r_start = uieditfield(app.figure1, 'text');
            app.edit_LLExp_r_start.ValueChangedFcn = createCallbackFcn(app, @edit_LLExp_r_start_Callback, true);
            app.edit_LLExp_r_start.Tag = 'edit_LLExp_r_start';
            app.edit_fracDim_r_start = uieditfield(app.figure1, 'text');
            app.edit_fracDim_r_start.ValueChangedFcn = createCallbackFcn(app, @edit_fracDim_r_start_Callback, true);
            app.edit_fracDim_r_start.Tag = 'edit_fracDim_r_start';
            app.edit_apEn_r_start = uieditfield(app.figure1, 'text');
            app.edit_apEn_r_start.ValueChangedFcn = createCallbackFcn(app, @edit_apEn_r_start_Callback, true);
            app.edit_apEn_r_start.Tag = 'edit_apEn_r_start';
            app.edit_sampEn_r_start = uieditfield(app.figure1, 'text');
            app.edit_sampEn_r_start.ValueChangedFcn = createCallbackFcn(app, @edit_sampEn_r_start_Callback, true);
            app.edit_sampEn_r_start.Tag = 'edit_sampEn_r_start';
            app.edit_waveletMSE_r_start = uieditfield(app.figure1, 'text');
            app.edit_waveletMSE_r_start.ValueChangedFcn = createCallbackFcn(app, @edit_waveletMSE_r_start_Callback, true);
            app.edit_waveletMSE_r_start.Tag = 'edit_waveletMSE_r_start';
            app.edit_fuzzyEn_r_start = uieditfield(app.figure1, 'text');
            app.edit_fuzzyEn_r_start.ValueChangedFcn = createCallbackFcn(app, @edit_fuzzyEn_r_start_Callback, true);
            app.edit_fuzzyEn_r_start.Tag = 'edit_fuzzyEn_r_start';
            app.edit_permEn_r_start = uieditfield(app.figure1, 'text');
            app.edit_permEn_r_start.ValueChangedFcn = createCallbackFcn(app, @edit_permEn_r_start_Callback, true);
            app.edit_permEn_r_start.Tag = 'edit_permEn_r_start';
            app.edit_lempelZiv_r_end = uieditfield(app.figure1, 'text');
            app.edit_lempelZiv_r_end.ValueChangedFcn = createCallbackFcn(app, @edit_lempelZiv_r_end_Callback, true);
            app.edit_lempelZiv_r_end.Tag = 'edit_lempelZiv_r_end';
            app.edit_hurstExp_r_end = uieditfield(app.figure1, 'text');
            app.edit_hurstExp_r_end.ValueChangedFcn = createCallbackFcn(app, @edit_hurstExp_r_end_Callback, true);
            app.edit_hurstExp_r_end.Tag = 'edit_hurstExp_r_end';
            app.edit_LLExp_r_end = uieditfield(app.figure1, 'text');
            app.edit_LLExp_r_end.ValueChangedFcn = createCallbackFcn(app, @edit_LLExp_r_end_Callback, true);
            app.edit_LLExp_r_end.Tag = 'edit_LLExp_r_end';
            app.edit_fracDim_r_end = uieditfield(app.figure1, 'text');
            app.edit_fracDim_r_end.ValueChangedFcn = createCallbackFcn(app, @edit_fracDim_r_end_Callback, true);
            app.edit_fracDim_r_end.Tag = 'edit_fracDim_r_end';
            app.edit_apEn_r_end = uieditfield(app.figure1, 'text');
            app.edit_apEn_r_end.ValueChangedFcn = createCallbackFcn(app, @edit_apEn_r_end_Callback, true);
            app.edit_apEn_r_end.Tag = 'edit_apEn_r_end';
            app.edit_waveletMSE_r_end = uieditfield(app.figure1, 'text');
            app.edit_waveletMSE_r_end.ValueChangedFcn = createCallbackFcn(app, @edit_waveletMSE_r_end_Callback, true);
            app.edit_waveletMSE_r_end.Tag = 'edit_waveletMSE_r_end';
            app.edit_fuzzyEn_r_end = uieditfield(app.figure1, 'text');
            app.edit_fuzzyEn_r_end.ValueChangedFcn = createCallbackFcn(app, @edit_fuzzyEn_r_end_Callback, true);
            app.edit_fuzzyEn_r_end.Tag = 'edit_fuzzyEn_r_end';
            app.edit_permEn_r_end = uieditfield(app.figure1, 'text');
            app.edit_permEn_r_end.ValueChangedFcn = createCallbackFcn(app, @edit_permEn_r_end_Callback, true);
            app.edit_permEn_r_end.Tag = 'edit_permEn_r_end';
            app.edit_lempelZiv_scale_start = uieditfield(app.figure1, 'text');
            app.edit_lempelZiv_scale_start.ValueChangedFcn = createCallbackFcn(app, @edit_lempelZiv_scale_start_Callback, true);
            app.edit_lempelZiv_scale_start.Tag = 'edit_lempelZiv_scale_start';
            app.edit_hurstExp_scale_start = uieditfield(app.figure1, 'text');
            app.edit_hurstExp_scale_start.ValueChangedFcn = createCallbackFcn(app, @edit_hurstExp_scale_start_Callback, true);
            app.edit_hurstExp_scale_start.Tag = 'edit_hurstExp_scale_start';
            app.edit_LLExp_scale_start = uieditfield(app.figure1, 'text');
            app.edit_LLExp_scale_start.ValueChangedFcn = createCallbackFcn(app, @edit_LLExp_scale_start_Callback, true);
            app.edit_LLExp_scale_start.Tag = 'edit_LLExp_scale_start';
            app.edit_fracDim_scale_start = uieditfield(app.figure1, 'text');
            app.edit_fracDim_scale_start.ValueChangedFcn = createCallbackFcn(app, @edit_fracDim_scale_start_Callback, true);
            app.edit_fracDim_scale_start.Tag = 'edit_fracDim_scale_start';
            app.edit_apEn_scale_start = uieditfield(app.figure1, 'text');
            app.edit_apEn_scale_start.ValueChangedFcn = createCallbackFcn(app, @edit_apEn_scale_start_Callback, true);
            app.edit_apEn_scale_start.Tag = 'edit_apEn_scale_start';
            app.edit_waveletMSE_scale_start = uieditfield(app.figure1, 'text');
            app.edit_waveletMSE_scale_start.ValueChangedFcn = createCallbackFcn(app, @edit_waveletMSE_scale_start_Callback, true);
            app.edit_waveletMSE_scale_start.Tag = 'edit_waveletMSE_scale_start';
            app.edit_fuzzyEn_scale_start = uieditfield(app.figure1, 'text');
            app.edit_fuzzyEn_scale_start.ValueChangedFcn = createCallbackFcn(app, @edit_fuzzyEn_scale_start_Callback, true);
            app.edit_fuzzyEn_scale_start.Tag = 'edit_fuzzyEn_scale_start';
            app.edit_permEn_scale_start = uieditfield(app.figure1, 'text');
            app.edit_permEn_scale_start.ValueChangedFcn = createCallbackFcn(app, @edit_permEn_scale_start_Callback, true);
            app.edit_permEn_scale_start.Tag = 'edit_permEn_scale_start';
            app.edit_lempelZiv_scale_end = uieditfield(app.figure1, 'text');
            app.edit_lempelZiv_scale_end.ValueChangedFcn = createCallbackFcn(app, @edit_lempelZiv_scale_end_Callback, true);
            app.edit_lempelZiv_scale_end.Tag = 'edit_lempelZiv_scale_end';
            app.edit_hurstExp_scale_end = uieditfield(app.figure1, 'text');
            app.edit_hurstExp_scale_end.ValueChangedFcn = createCallbackFcn(app, @edit_hurstExp_scale_end_Callback, true);
            app.edit_hurstExp_scale_end.Tag = 'edit_hurstExp_scale_end';
            app.edit_LLExp_scale_end = uieditfield(app.figure1, 'text');
            app.edit_LLExp_scale_end.ValueChangedFcn = createCallbackFcn(app, @edit_LLExp_scale_end_Callback, true);
            app.edit_LLExp_scale_end.Tag = 'edit_LLExp_scale_end';
            app.edit_fracDim_scale_end = uieditfield(app.figure1, 'text');
            app.edit_fracDim_scale_end.ValueChangedFcn = createCallbackFcn(app, @edit_fracDim_scale_end_Callback, true);
            app.edit_fracDim_scale_end.Tag = 'edit_fracDim_scale_end';
            app.edit_apEn_scale_end = uieditfield(app.figure1, 'text');
            app.edit_apEn_scale_end.ValueChangedFcn = createCallbackFcn(app, @edit_apEn_scale_end_Callback, true);
            app.edit_apEn_scale_end.Tag = 'edit_apEn_scale_end';
            app.edit_sampEn_scale_end = uieditfield(app.figure1, 'text');
            app.edit_sampEn_scale_end.ValueChangedFcn = createCallbackFcn(app, @edit_sampEn_scale_end_Callback, true);
            app.edit_sampEn_scale_end.Tag = 'edit_sampEn_scale_end';
            app.edit_waveletMSE_scale_end = uieditfield(app.figure1, 'text');
            app.edit_waveletMSE_scale_end.ValueChangedFcn = createCallbackFcn(app, @edit_waveletMSE_scale_end_Callback, true);
            app.edit_waveletMSE_scale_end.Tag = 'edit_waveletMSE_scale_end';
            app.edit_fuzzyEn_scale_end = uieditfield(app.figure1, 'text');
            app.edit_fuzzyEn_scale_end.ValueChangedFcn = createCallbackFcn(app, @edit_fuzzyEn_scale_end_Callback, true);
            app.edit_fuzzyEn_scale_end.Tag = 'edit_fuzzyEn_scale_end';
            app.edit_permEn_scale_end = uieditfield(app.figure1, 'text');
            app.edit_permEn_scale_end.ValueChangedFcn = createCallbackFcn(app, @edit_permEn_scale_end_Callback, true);
            app.edit_permEn_scale_end.Tag = 'edit_permEn_scale_end';
            app.text29 = uilabel(app.figure1);
            app.text29.Tag = 'text29';
            app.text29.Text = 'k';
            app.edit_fracDim_k_start = uieditfield(app.figure1, 'text');
            app.edit_fracDim_k_start.ValueChangedFcn = createCallbackFcn(app, @edit_fracDim_k_start_Callback, true);
            app.edit_fracDim_k_start.Tag = 'edit_fracDim_k_start';
            app.text30 = uilabel(app.figure1);
            app.text30.Tag = 'text30';
            app.text30.Text = 'n';
            app.edit_fuzzyEn_n_start = uieditfield(app.figure1, 'text');
            app.edit_fuzzyEn_n_start.ValueChangedFcn = createCallbackFcn(app, @edit_fuzzyEn_n_start_Callback, true);
            app.edit_fuzzyEn_n_start.Tag = 'edit_fuzzyEn_n_start';
            app.text31 = uilabel(app.figure1);
            app.text31.Tag = 'text31';
            app.text31.Text = 'tau';
            app.edit_fuzzyEn_tau_start = uieditfield(app.figure1, 'text');
            app.edit_fuzzyEn_tau_start.ValueChangedFcn = createCallbackFcn(app, @edit_fuzzyEn_tau_start_Callback, true);
            app.edit_fuzzyEn_tau_start.Tag = 'edit_fuzzyEn_tau_start';
            app.text32 = uilabel(app.figure1);
            app.text32.Tag = 'text32';
            app.text32.Text = 'delay';
            app.edit_permEn_delay_start = uieditfield(app.figure1, 'text');
            app.edit_permEn_delay_start.ValueChangedFcn = createCallbackFcn(app, @edit_permEn_delay_start_Callback, true);
            app.edit_permEn_delay_start.Tag = 'edit_permEn_delay_start';
            app.text33 = uilabel(app.figure1);
            app.text33.Tag = 'text33';
            app.text33.Text = 'order';
            app.edit_permEn_order_start = uieditfield(app.figure1, 'text');
            app.edit_permEn_order_start.ValueChangedFcn = createCallbackFcn(app, @edit_permEn_order_start_Callback, true);
            app.edit_permEn_order_start.Tag = 'edit_permEn_order_start';
            app.checkbox_normalize = uicheckbox(app.figure1);
            app.checkbox_normalize.Tag = 'checkbox_normalize';
            app.checkbox_normalize.Text = 'Normalize';
            app.edit_sampEn_r_end = uieditfield(app.figure1, 'text');
            app.edit_sampEn_r_end.ValueChangedFcn = createCallbackFcn(app, @edit_sampEn_r_end_Callback, true);
            app.edit_sampEn_r_end.Tag = 'edit_sampEn_r_end';
            app.edit_sampEn_scale_start = uieditfield(app.figure1, 'text');
            app.edit_sampEn_scale_start.ValueChangedFcn = createCallbackFcn(app, @edit_sampEn_scale_start_Callback, true);
            app.edit_sampEn_scale_start.Tag = 'edit_sampEn_scale_start';
            app.edit_inputDir = uieditfield(app.figure1, 'text');
            app.edit_inputDir.Tag = 'edit_inputDir';
            app.text34 = uilabel(app.figure1);
            app.text34.Tag = 'text34';
            app.text34.Text = 'Working Directory :';
            app.btn_inputDir = uibutton(app.figure1, 'push');
            app.btn_inputDir.ButtonPushedFcn = createCallbackFcn(app, @btn_inputDir_Callback, true);
            app.btn_inputDir.Tag = 'btn_inputDir';
            app.btn_inputDir.Text = '...';
            app.edit_subjectPattern = uieditfield(app.figure1, 'text');
            app.edit_subjectPattern.Tag = 'edit_subjectPattern';
            app.edit_subjectPattern.Tooltip = 'For Example: enter btest*/ for selecting test case folders starting with the phrase btest';
            app.text35 = uilabel(app.figure1);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Subject Pattern :';
            app.edit_fmriPattern = uieditfield(app.figure1, 'text');
            app.edit_fmriPattern.Tag = 'edit_fmriPattern';
            app.edit_fmriPattern.Tooltip = 'For Example: enter *.nii to select all files with .nii extension in the subject folder';
            app.text36 = uilabel(app.figure1);
            app.text36.Tag = 'text36';
            app.text36.Text = 'fMRI Pattern :';
            app.edit_filePattern = uieditfield(app.figure1, 'text');
            app.edit_filePattern.Tag = 'edit_filePattern';
            app.text37 = uilabel(app.figure1);
            app.text37.Tag = 'text37';
            app.text37.Text = 'File Pattern :';
            app.edit_outputDir = uieditfield(app.figure1, 'text');
            app.edit_outputDir.Tag = 'edit_outputDir';
            app.text38 = uilabel(app.figure1);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Output Directory :';
            app.btn_load = uibutton(app.figure1, 'push');
            app.btn_load.ButtonPushedFcn = createCallbackFcn(app, @btn_load_Callback, true);
            app.btn_load.Tag = 'btn_load';
            app.btn_load.Text = 'Select Subjects';
            app.text_dataInfo = uilabel(app.figure1);
            app.text_dataInfo.Tag = 'text_dataInfo';
            app.text_dataInfo.Text = '0 subjects, 0 scans loaded.';
            app.btn_run = uibutton(app.figure1, 'push');
            app.btn_run.ButtonPushedFcn = createCallbackFcn(app, @btn_run_Callback, true);
            app.btn_run.Tag = 'btn_run';
            app.btn_run.Text = 'Run';
            app.btn_outputDir = uibutton(app.figure1, 'push');
            app.btn_outputDir.ButtonPushedFcn = createCallbackFcn(app, @btn_outputDir_Callback, true);
            app.btn_outputDir.Tag = 'btn_outputDir';
            app.btn_outputDir.Text = '...';
            app.pb_brainMask = uibutton(app.figure1, 'push');
            app.pb_brainMask.ButtonPushedFcn = createCallbackFcn(app, @pb_brainMask_Callback, true);
            app.pb_brainMask.Tag = 'pb_brainMask';
            app.pb_brainMask.Text = 'Select Brain Mask';
            app.edit_fuzzyEn_tau_end = uieditfield(app.figure1, 'text');
            app.edit_fuzzyEn_tau_end.ValueChangedFcn = createCallbackFcn(app, @edit_fuzzyEn_tau_end_Callback, true);
            app.edit_fuzzyEn_tau_end.Tag = 'edit_fuzzyEn_tau_end';
            app.edit_permEn_delay_end = uieditfield(app.figure1, 'text');
            app.edit_permEn_delay_end.ValueChangedFcn = createCallbackFcn(app, @edit_permEn_delay_end_Callback, true);
            app.edit_permEn_delay_end.Tag = 'edit_permEn_delay_end';
            app.edit_permEn_order_end = uieditfield(app.figure1, 'text');
            app.edit_permEn_order_end.Tag = 'edit_permEn_order_end';
            app.edit_fuzzyEn_n_end = uieditfield(app.figure1, 'text');
            app.edit_fuzzyEn_n_end.ValueChangedFcn = createCallbackFcn(app, @edit_fuzzyEn_n_end_Callback, true);
            app.edit_fuzzyEn_n_end.Tag = 'edit_fuzzyEn_n_end';
            app.edit_fracDim_k_end = uieditfield(app.figure1, 'text');
            app.edit_fracDim_k_end.ValueChangedFcn = createCallbackFcn(app, @edit_fracDim_k_end_Callback, true);
            app.edit_fracDim_k_end.Tag = 'edit_fracDim_k_end';
            app.btn_viewOutput = uibutton(app.figure1, 'push');
            app.btn_viewOutput.ButtonPushedFcn = createCallbackFcn(app, @btn_viewOutput_Callback, true);
            app.btn_viewOutput.Tag = 'btn_viewOutput';
            app.btn_viewOutput.Text = 'View Input ';
            app.text40 = uilabel(app.figure1);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Batch Processing';
            app.text41 = uilabel(app.figure1);
            app.text41.Tag = 'text41';
            app.text41.Text = 'Select the following fields for loading a batch of subjects:';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'm';
            app.text46 = uilabel(app.figure1);
            app.text46.Tag = 'text46';
            app.text46.Text = 'Complexity Metrics:';
            app.text47 = uilabel(app.figure1);
            app.text47.Tag = 'text47';
            app.text47.Text = '~';
            app.text48 = uilabel(app.figure1);
            app.text48.Tag = 'text48';
            app.text48.Text = '~';
            app.text49 = uilabel(app.figure1);
            app.text49.Tag = 'text49';
            app.text49.Text = '~';
            app.text50 = uilabel(app.figure1);
            app.text50.Tag = 'text50';
            app.text50.Text = '~';
            app.text51 = uilabel(app.figure1);
            app.text51.Tag = 'text51';
            app.text51.Text = '~';
            app.text52 = uilabel(app.figure1);
            app.text52.Tag = 'text52';
            app.text52.Text = '~';
            app.text53 = uilabel(app.figure1);
            app.text53.Tag = 'text53';
            app.text53.Text = '~';
            app.text54 = uilabel(app.figure1);
            app.text54.Tag = 'text54';
            app.text54.Text = '~';
            app.text55 = uilabel(app.figure1);
            app.text55.Tag = 'text55';
            app.text55.Text = '~';
            app.text56 = uilabel(app.figure1);
            app.text56.Tag = 'text56';
            app.text56.Text = '~';
            app.text57 = uilabel(app.figure1);
            app.text57.Tag = 'text57';
            app.text57.Text = '~';
            app.text58 = uilabel(app.figure1);
            app.text58.Tag = 'text58';
            app.text58.Text = '~';
            app.text59 = uilabel(app.figure1);
            app.text59.Tag = 'text59';
            app.text59.Text = '~';
            app.text60 = uilabel(app.figure1);
            app.text60.Tag = 'text60';
            app.text60.Text = '~';
            app.text61 = uilabel(app.figure1);
            app.text61.Tag = 'text61';
            app.text61.Text = '~';
            app.text62 = uilabel(app.figure1);
            app.text62.Tag = 'text62';
            app.text62.Text = '~';
            app.text63 = uilabel(app.figure1);
            app.text63.Tag = 'text63';
            app.text63.Text = '~';
            app.text64 = uilabel(app.figure1);
            app.text64.Tag = 'text64';
            app.text64.Text = '~';
            app.text65 = uilabel(app.figure1);
            app.text65.Tag = 'text65';
            app.text65.Text = '~';
            app.text66 = uilabel(app.figure1);
            app.text66.Tag = 'text66';
            app.text66.Text = '~';
            app.text67 = uilabel(app.figure1);
            app.text67.Tag = 'text67';
            app.text67.Text = '~';
            app.text68 = uilabel(app.figure1);
            app.text68.Tag = 'text68';
            app.text68.Text = '~';
            app.text69 = uilabel(app.figure1);
            app.text69.Tag = 'text69';
            app.text69.Text = '~';
            app.SaveLoadProjectPanel = uipanel(app.figure1);
            app.SaveLoadProjectPanel.Title = 'Save/Load Project';
            app.pushbutton11 = uibutton(app.SaveLoadProjectPanel, 'push');
            app.pushbutton11.Tag = 'pushbutton11';
            app.pushbutton11.Text = 'Save Project';
            app.pushbutton12 = uibutton(app.SaveLoadProjectPanel, 'push');
            app.pushbutton12.Tag = 'pushbutton12';
            app.pushbutton12.Text = 'Load Project';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = batchProcessing_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)batchProcessing_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end